classdef Formulas_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        equationTab                   matlab.ui.container.Tab
        equationUIAxes                matlab.ui.control.UIAxes
        PhngtrnhLabel                 matlab.ui.control.Label
        funcEditField                 matlab.ui.control.EditField
        intervalEditField             matlab.ui.control.EditField
        MethodLabel                   matlab.ui.control.Label
        equation_methodListBox        matlab.ui.control.ListBox
        intervalLabel                 matlab.ui.control.Label
        aEquation_EditField           matlab.ui.control.NumericEditField
        bEquation_EditField           matlab.ui.control.NumericEditField
        RootLabel                     matlab.ui.control.Label
        rootEditField                 matlab.ui.control.NumericEditField
        RepeatLabel                   matlab.ui.control.Label
        repeatEditField               matlab.ui.control.NumericEditField
        equationButton                matlab.ui.control.Button
        ErrorTextAreaLabel            matlab.ui.control.Label
        errorTextArea                 matlab.ui.control.TextArea
        PhngtrnhLabel_5               matlab.ui.control.Label
        epsilonEditField              matlab.ui.control.EditField
        interpolationTab              matlab.ui.container.Tab
        interpolationUIAxes           matlab.ui.control.UIAxes
        interpolationButton           matlab.ui.control.Button
        PhngtrnhLabel_2               matlab.ui.control.Label
        interpolation_XField          matlab.ui.control.EditField
        PhngtrnhLabel_3               matlab.ui.control.Label
        interpolation_YField          matlab.ui.control.EditField
        MethodLabel_2                 matlab.ui.control.Label
        interpolation_methodListBox   matlab.ui.control.ListBox
        interpolationLabel            matlab.ui.control.Label
        interpolation_x0EditField     matlab.ui.control.NumericEditField
        interpolation_rootField       matlab.ui.control.NumericEditField
        PhngtrnhLabel_4               matlab.ui.control.Label
        polynomialArea                matlab.ui.control.TextArea
        regressionTab                 matlab.ui.container.Tab
        regressionUIAxes              matlab.ui.control.UIAxes
        RunButton_Regression          matlab.ui.control.Button
        PhngtrnhLabel_6               matlab.ui.control.Label
        X_Field_Regression            matlab.ui.control.EditField
        PhngtrnhLabel_7               matlab.ui.control.Label
        Y_Field_Regression            matlab.ui.control.EditField
        MethodLabel_3                 matlab.ui.control.Label
        methodListBox_Regression      matlab.ui.control.ListBox
        RegressionpolynomialLabel     matlab.ui.control.Label
        RegressionPoly                matlab.ui.control.EditField
        YLabel                        matlab.ui.control.Label
        EstimatedYValue_Regression    matlab.ui.control.NumericEditField
        XLabel                        matlab.ui.control.Label
        EstimatedXValue_Regression    matlab.ui.control.NumericEditField
        RegressionpolynomialTextAreaLabel  matlab.ui.control.Label
        RegressionpolynomialTextArea  matlab.ui.control.TextArea
        derivationTab                 matlab.ui.container.Tab
        derivationUIAxes              matlab.ui.control.UIAxes
        integrationTab                matlab.ui.container.Tab
        integrationUIAxes             matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: equationButton
        function equationButtonPushed(app, event)
            method = app.equation_methodListBox.Value;
            fx = app.funcEditField.Value;
            a = app.aEquation_EditField.Value;
            b = app.bEquation_EditField.Value;
            e = str2double(app.epsilonEditField.Value);
            fprintf('%s\n', method);
            if strcmp(method, 'Bisection')
                [x0, n, er] = bisection(fx, a, b, e);
            elseif strcmp(method, 'Interation')
                [x0, n] = interation(fx, a, b, e);
            elseif strcmp(method, 'Newton')
                [x0, n, er] = newtonEquation(fx, a, b, e);
            end
            % Print error
            app.errorTextArea.Value = er;
            % Plot the graph
            fx = str2func(['@(x)', fx]);
            t = a:(b-a)/100:b;
            y = arrayfun(fx, t);
            plot(app.equationUIAxes, t, y);
            app.rootEditField.Value = x0;
            app.repeatEditField.Value = n;
        end

        % Button pushed function: interpolationButton
        function interpolationButtonPushed(app, event)
            method = app.interpolation_methodListBox.Value;
            x = str2double(strsplit(app.interpolation_XField.Value, ', ')); 
            y = str2double(strsplit(app.interpolation_YField.Value, ', ')); 
            x0 = app.interpolation_x0EditField.Value;
            
            if strcmp(method, 'Lagrange')
                [P, result] = lagrange(x, y, x0);
            elseif strcmp(method, 'Newton')
                [P, result] = newtonInterpolation(x, y, x0);
            end
            % Print result and polynomial
            P_str = string(P);
            app.interpolation_rootField.Value = result;
            app.polynomialArea.Value = P_str;
            % Plot the graph
            a = x(1);
            b = x(length(x));
            t = a : (b - a)/100 : b;
            y_P = arrayfun(matlabFunction(P), t);
            hold(app.interpolationUIAxes, "off");
            plot(app.interpolationUIAxes, t, y_P);
            hold(app.interpolationUIAxes, "on");
            plot(app.interpolationUIAxes, x, y, '*');
        end

        % Button pushed function: RunButton_Regression
        function RunButton_RegressionPushed(app, event)
            interactRG = app.methodListBox_Regression.Value;
            %digits(7);
            x1 = str2double(strsplit(app.X_Field_Regression.Value, ', ')); 
            y1 = str2double(strsplit(app.Y_Field_Regression.Value, ', ')); 
            syms x;
            if strcmp(interactRG, 'Linear')
                [a,b] = Regress(x1,y1);
                fx = (x*vpa(a,4))+vpa(b,4);
            elseif strcmp(interactRG, 'Exponential')
                [b,a] = RegressE(x1,y1);
                fx = vpa(a,4)*x^vpa(b,4);
            elseif strcmp(interactRG, 'EulerExp')
                %[a,b] = EulerExp(x1,y1);
            end
            y = arrayfun(matlabFunction(fx),x1);
            hold(app.regressionUIAxes,"off");
            plot(app.regressionUIAxes,x1,y1,"*r");
            hold(app.regressionUIAxes,"on");
            plot(app.regressionUIAxes,x1,y,"b");
            app.RegressionpolynomialTextArea.Value = string(fx);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.equationTab = uitab(app.TabGroup);
            app.equationTab.Title = 'Equation';
            app.equationUIAxes = uiaxes(app.equationTab);
            zlabel(app.equationUIAxes, 'Z')
            app.PhngtrnhLabel = uilabel(app.equationTab);
            app.PhngtrnhLabel.Text = 'Function';
            app.funcEditField = uieditfield(app.equationTab, 'text');
            app.intervalEditField = uieditfield(app.equationTab, 'text');
            app.MethodLabel = uilabel(app.equationTab);
            app.MethodLabel.Text = 'Method';
            app.equation_methodListBox = uilistbox(app.equationTab);
            app.equation_methodListBox.Items = {'Bisection', 'Interation', 'Newton'};
            app.equation_methodListBox.Value = 'Bisection';
            app.intervalLabel = uilabel(app.equationTab);
            app.intervalLabel.Text = 'Interval';
            app.aEquation_EditField = uieditfield(app.equationTab, 'numeric');
            app.bEquation_EditField = uieditfield(app.equationTab, 'numeric');
            app.RootLabel = uilabel(app.equationTab);
            app.RootLabel.Text = 'Solution';
            app.rootEditField = uieditfield(app.equationTab, 'numeric');
            app.rootEditField.Editable = 'off';
            app.RepeatLabel = uilabel(app.equationTab);
            app.RepeatLabel.Text = 'Repeat';
            app.repeatEditField = uieditfield(app.equationTab, 'numeric');
            app.repeatEditField.Editable = 'off';
            app.equationButton = uibutton(app.equationTab, 'push');
            app.equationButton.ButtonPushedFcn = createCallbackFcn(app, @equationButtonPushed, true);
            app.equationButton.Text = 'Run';
            app.ErrorTextAreaLabel = uilabel(app.equationTab);
            app.ErrorTextAreaLabel.Text = 'Error';
            app.errorTextArea = uitextarea(app.equationTab);
            app.errorTextArea.Editable = 'off';
            app.PhngtrnhLabel_5 = uilabel(app.equationTab);
            app.PhngtrnhLabel_5.Text = 'Error range';
            app.epsilonEditField = uieditfield(app.equationTab, 'text');
            app.interpolationTab = uitab(app.TabGroup);
            app.interpolationTab.Title = 'Interpolation';
            app.interpolationUIAxes = uiaxes(app.interpolationTab);
            zlabel(app.interpolationUIAxes, 'Z')
            app.interpolationButton = uibutton(app.interpolationTab, 'push');
            app.interpolationButton.ButtonPushedFcn = createCallbackFcn(app, @interpolationButtonPushed, true);
            app.interpolationButton.Text = 'Run';
            app.PhngtrnhLabel_2 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_2.Text = 'Array x';
            app.interpolation_XField = uieditfield(app.interpolationTab, 'text');
            app.PhngtrnhLabel_3 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_3.Text = 'Array y';
            app.interpolation_YField = uieditfield(app.interpolationTab, 'text');
            app.MethodLabel_2 = uilabel(app.interpolationTab);
            app.MethodLabel_2.Text = 'Method';
            app.interpolation_methodListBox = uilistbox(app.interpolationTab);
            app.interpolation_methodListBox.Items = {'Lagrange', 'Newton'};
            app.interpolation_methodListBox.Value = 'Lagrange';
            app.interpolationLabel = uilabel(app.interpolationTab);
            app.interpolationLabel.Text = 'Value to interpolate';
            app.interpolation_x0EditField = uieditfield(app.interpolationTab, 'numeric');
            app.interpolation_rootField = uieditfield(app.interpolationTab, 'numeric');
            app.interpolation_rootField.Editable = 'off';
            app.PhngtrnhLabel_4 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_4.Text = 'Interpolation polynomial';
            app.polynomialArea = uitextarea(app.interpolationTab);
            app.polynomialArea.Editable = 'off';
            app.regressionTab = uitab(app.TabGroup);
            app.regressionTab.Title = 'Regression';
            app.regressionUIAxes = uiaxes(app.regressionTab);
            title(app.regressionUIAxes, 'Graph')
            app.RunButton_Regression = uibutton(app.regressionTab, 'push');
            app.RunButton_Regression.ButtonPushedFcn = createCallbackFcn(app, @RunButton_RegressionPushed, true);
            app.RunButton_Regression.Text = 'Run';
            app.PhngtrnhLabel_6 = uilabel(app.regressionTab);
            app.PhngtrnhLabel_6.Text = 'Array x';
            app.X_Field_Regression = uieditfield(app.regressionTab, 'text');
            app.X_Field_Regression.Value = '6, 7, 11, 15, 17, 21, 23, 29, 29, 37, 39';
            app.PhngtrnhLabel_7 = uilabel(app.regressionTab);
            app.PhngtrnhLabel_7.Text = 'Array y';
            app.Y_Field_Regression = uieditfield(app.regressionTab, 'text');
            app.Y_Field_Regression.Value = '29, 21, 29, 14, 21, 15, 7, 7, 13, 0, 3';
            app.MethodLabel_3 = uilabel(app.regressionTab);
            app.MethodLabel_3.Text = 'Method';
            app.methodListBox_Regression = uilistbox(app.regressionTab);
            app.methodListBox_Regression.Items = {'Linear', 'Exponential', 'EulerExp'};
            app.methodListBox_Regression.Value = 'Linear';
            app.RegressionpolynomialLabel = uilabel(app.regressionTab);
            app.RegressionpolynomialLabel.Text = 'Regression polynomial';
            app.RegressionPoly = uieditfield(app.regressionTab, 'text');
            app.RegressionPoly.Editable = 'off';
            app.YLabel = uilabel(app.regressionTab);
            app.YLabel.Text = 'Y';
            app.EstimatedYValue_Regression = uieditfield(app.regressionTab, 'numeric');
            app.EstimatedYValue_Regression.Editable = 'off';
            app.XLabel = uilabel(app.regressionTab);
            app.XLabel.Text = 'X';
            app.EstimatedXValue_Regression = uieditfield(app.regressionTab, 'numeric');
            app.EstimatedXValue_Regression.Value = 56;
            app.RegressionpolynomialTextAreaLabel = uilabel(app.regressionTab);
            app.RegressionpolynomialTextAreaLabel.Text = 'Regression polynomial';
            app.RegressionpolynomialTextArea = uitextarea(app.regressionTab);
            app.derivationTab = uitab(app.TabGroup);
            app.derivationTab.Title = 'Derivation';
            app.derivationUIAxes = uiaxes(app.derivationTab);
            zlabel(app.derivationUIAxes, 'Z')
            app.integrationTab = uitab(app.TabGroup);
            app.integrationTab.Title = 'Integration';
            app.integrationUIAxes = uiaxes(app.integrationTab);
            zlabel(app.integrationUIAxes, 'Z')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Formulas_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end